classdef app1 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                matlab.ui.Figure
        TabGroup                matlab.ui.container.TabGroup
        DataTab                 matlab.ui.container.Tab
        DataViewerPanel         matlab.ui.container.Panel
        UITable                 matlab.ui.control.Table
        filepathEditField       matlab.ui.control.EditField
        filepathEditFieldLabel  matlab.ui.control.Label
        DataManagementPanel     matlab.ui.container.Panel
        StandardizationButton   matlab.ui.control.Button
        LoadButton              matlab.ui.control.Button
        ClusteringTab           matlab.ui.container.Tab
        HierarchicalClusteringViewerPanel  matlab.ui.container.Panel
        UIAxes                  matlab.ui.control.UIAxes
        ParameterSetupPanel     matlab.ui.container.Panel
        ClusteringButton        matlab.ui.control.Button
        linkageDropDown         matlab.ui.control.DropDown
        linkageDropDownLabel    matlab.ui.control.Label
        pdistDropDown           matlab.ui.control.DropDown
        pdistDropDownLabel      matlab.ui.control.Label
    end

    
    properties (Access = private)
        check_zscore % check whether standardization is performed
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: LoadButton
        function LoadButtonPushed(app, event)
            [filename, filepath] = uigetfile('*.*', 'Please select a file to import.');
            if filename
                app.filepathEditField.Value = [filepath, filename];
                data = readtable([filepath, filename]);
                app.UITable.Data = data;
                app.UITable.ColumnName = data.Properties.VariableNames;
                app.check_zscore = 0; % 一讀完檔，就先設為 0, 有做 standardization，才改為 1
                msgbox('Data import is done!')
                % assignin('base','app', app) % assign 'app' to workspace
            end
        end

        % Button pushed function: StandardizationButton
        function StandardizationButtonPushed(app, event)
            data = app.UITable.Data;
            Z = zscore(table2array(data(2:end, 2:end)));
            data(2:end, 2:end) = array2table(Z);
            app.UITable.Data = data;
            app.check_zscore = 1;
            msgbox('Standardization is done!')
        end

        % Button pushed function: ClusteringButton
        function ClusteringButtonPushed(app, event)
            if app.check_zscore == 0
                errordlg('Please standardize data first!')
                return
            end

            data = app.UITable.Data;
            numdata = table2array(data(2:end, 2:end));
            datalabel = table2cell(data(2:end, 1));

            D = pdist(numdata, app.pdistDropDown.Value);
            Ctree = linkage(D, app.linkageDropDown.Value);

            [H, T, outperm] = dendrogram(Ctree, 0, 'labels', datalabel, 'Orientation', 'top')
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.TabGroup = uitabgroup(app.UIFigure);
            app.DataTab = uitab(app.TabGroup);
            app.DataTab.Title = 'Data';
            app.DataManagementPanel = uipanel(app.DataTab);
            app.DataManagementPanel.Title = 'Data Management';
            app.LoadButton = uibutton(app.DataManagementPanel, 'push');
            app.LoadButton.ButtonPushedFcn = createCallbackFcn(app, @LoadButtonPushed, true);
            app.LoadButton.Text = 'Load';
            app.StandardizationButton = uibutton(app.DataManagementPanel, 'push');
            app.StandardizationButton.ButtonPushedFcn = createCallbackFcn(app, @StandardizationButtonPushed, true);
            app.StandardizationButton.Text = 'Standardization';
            app.DataViewerPanel = uipanel(app.DataTab);
            app.DataViewerPanel.Title = 'Data Viewer';
            app.filepathEditFieldLabel = uilabel(app.DataViewerPanel);
            app.filepathEditFieldLabel.Text = 'file path';
            app.filepathEditField = uieditfield(app.DataViewerPanel, 'text');
            app.UITable = uitable(app.DataViewerPanel);
            app.UITable.ColumnName = {'Column 1'; 'Column 2'; 'Column 3'; 'Column 4'};
            app.UITable.RowName = {};
            app.ClusteringTab = uitab(app.TabGroup);
            app.ClusteringTab.Title = 'Clustering';
            app.ParameterSetupPanel = uipanel(app.ClusteringTab);
            app.ParameterSetupPanel.Title = 'Parameter Setup';
            app.pdistDropDownLabel = uilabel(app.ParameterSetupPanel);
            app.pdistDropDownLabel.Text = 'pdist';
            app.pdistDropDown = uidropdown(app.ParameterSetupPanel);
            app.pdistDropDown.Items = {'euclidean', 'squaredeuclidean', 'correlation'};
            app.pdistDropDown.Value = 'euclidean';
            app.linkageDropDownLabel = uilabel(app.ParameterSetupPanel);
            app.linkageDropDownLabel.Text = 'linkage';
            app.linkageDropDown = uidropdown(app.ParameterSetupPanel);
            app.linkageDropDown.Items = {'complete', 'average', 'single'};
            app.linkageDropDown.Value = 'complete';
            app.ClusteringButton = uibutton(app.ParameterSetupPanel, 'push');
            app.ClusteringButton.ButtonPushedFcn = createCallbackFcn(app, @ClusteringButtonPushed, true);
            app.ClusteringButton.Text = 'Clustering';
            app.HierarchicalClusteringViewerPanel = uipanel(app.ClusteringTab);
            app.HierarchicalClusteringViewerPanel.Title = 'Hierarchical Clustering Viewer';
            app.UIAxes = uiaxes(app.HierarchicalClusteringViewerPanel);
            title(app.UIAxes, 'Title')
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Y')
            zlabel(app.UIAxes, 'Z')
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = app1

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end