classdef demo2 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                matlab.ui.Figure
        TabGroup                matlab.ui.container.TabGroup
        DataTab                 matlab.ui.container.Tab
        DataManagementPanel     matlab.ui.container.Panel
        LoadButton              matlab.ui.control.Button
        StandardizationButton   matlab.ui.control.Button
        DataViewPanel           matlab.ui.container.Panel
        FilepathEditFieldLabel  matlab.ui.control.Label
        FilepathEditField       matlab.ui.control.EditField
        UITable                 matlab.ui.control.Table
        ClusteringTab           matlab.ui.container.Tab
        ParameterSetupPanel     matlab.ui.container.Panel
        ClusteringButton        matlab.ui.control.Button
        pdistLabel              matlab.ui.control.Label
        pdistDropDown           matlab.ui.control.DropDown
        linkageDropDownLabel    matlab.ui.control.Label
        linkageDropDown         matlab.ui.control.DropDown
        HierarchicalClusteringViewerPanel  matlab.ui.container.Panel
        UIAxes                  matlab.ui.control.UIAxes
    end

    
    properties (Access = private)
        check_zscore % check value of standardization
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: LoadButton
        function LoadButtonPushed(app, event)
            [filename,filepath] = uigetfile('*.*','Please select a file to import.');
            if filename
                app.FilepathEditField.Value = [filepath, filename];
                data = readtable([filepath,filename]);
                app.UITable.Data = data;
                app.UITable.ColumnName = data.Properties.VariableNames;
                app.check_zscore = 0;
                
                msgbox('Data import is done!')
                assignin('base','app',app) % assign 'app' to workspace
            end
        end

        % Button pushed function: StandardizationButton
        function StandardizationButtonPushed(app, event)
            data = app.UITable.Data;
            numdata = table2array(data(2:end,2:end));
            Z = zscore(numdata);
            data(2:end,2:end) = array2table(Z);
            app.UITable.Data = data;
            
            app.check_zscore = 1;
            
            msgbox('Standardization is done!')
        end

        % Button pushed function: ClusteringButton
        function ClusteringButtonPushed(app, event)
            if app.check_zscore == 0
                errordlg('Please standardize data first!')
                return
            end
            
            % perform hierarchical clustering
            data = app.UITable.Data;
            numdata = table2array(data(2:end,2:end));
            datalabel = table2cell(data(2:end,1));
            
            D=pdist(numdata,app.pdistDropDown.Value);
            Ctree=linkage(D,app.linkageDropDown.Value);
            
            [H,T,outperm]=dendrogram(Ctree,0,'Labels',datalabel,'Orientation','top');
            
            plot(app.UIAxes,0)
            hold(app.UIAxes) % hold axes                     
            for i=1:length(H)   
               plot(app.UIAxes,H(i).XData,H(i).YData,'color','b')
            end
            hold(app.UIAxes)  % release axes
            close(gcf) % close the figure generated by dendrogram
            
            app.UIAxes.XTick = 1:length(datalabel);
            app.UIAxes.XTickLabel = datalabel(outperm);
            app.UIAxes.XTickLabelRotation = 90;
            app.UIAxes.XLabel.String = '';
            app.UIAxes.YLabel.String = '';
            app.UIAxes.YGrid = 'on';
            
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'UI Figure';
            app.TabGroup = uitabgroup(app.UIFigure);
            app.DataTab = uitab(app.TabGroup);
            app.DataTab.Title = 'Data';
            app.DataManagementPanel = uipanel(app.DataTab);
            app.DataManagementPanel.Title = 'Data Management';
            app.LoadButton = uibutton(app.DataManagementPanel, 'push');
            app.LoadButton.ButtonPushedFcn = createCallbackFcn(app, @LoadButtonPushed, true);
            app.LoadButton.Text = 'Load';
            app.StandardizationButton = uibutton(app.DataManagementPanel, 'push');
            app.StandardizationButton.ButtonPushedFcn = createCallbackFcn(app, @StandardizationButtonPushed, true);
            app.StandardizationButton.Text = 'Standardization';
            app.DataViewPanel = uipanel(app.DataTab);
            app.DataViewPanel.Title = 'Data View';
            app.FilepathEditFieldLabel = uilabel(app.DataViewPanel);
            app.FilepathEditFieldLabel.Text = 'File path';
            app.FilepathEditField = uieditfield(app.DataViewPanel, 'text');
            app.UITable = uitable(app.DataViewPanel);
            app.UITable.ColumnName = {'Column 1'; 'Column 2'; 'Column 3'; 'Column 4'};
            app.UITable.RowName = {};
            app.ClusteringTab = uitab(app.TabGroup);
            app.ClusteringTab.Title = 'Clustering';
            app.ParameterSetupPanel = uipanel(app.ClusteringTab);
            app.ParameterSetupPanel.Title = 'Parameter Setup';
            app.ClusteringButton = uibutton(app.ParameterSetupPanel, 'push');
            app.ClusteringButton.ButtonPushedFcn = createCallbackFcn(app, @ClusteringButtonPushed, true);
            app.ClusteringButton.Text = 'Clustering';
            app.pdistLabel = uilabel(app.ParameterSetupPanel);
            app.pdistLabel.Text = 'pdist';
            app.pdistDropDown = uidropdown(app.ParameterSetupPanel);
            app.pdistDropDown.Items = {'euclidean', 'squaredeuclidean', 'correlation'};
            app.pdistDropDown.Value = 'euclidean';
            app.linkageDropDownLabel = uilabel(app.ParameterSetupPanel);
            app.linkageDropDownLabel.Text = 'linkage';
            app.linkageDropDown = uidropdown(app.ParameterSetupPanel);
            app.linkageDropDown.Items = {'complete', 'average', 'single'};
            app.linkageDropDown.Value = 'complete';
            app.HierarchicalClusteringViewerPanel = uipanel(app.ClusteringTab);
            app.HierarchicalClusteringViewerPanel.Title = 'Hierarchical Clustering Viewer';
            app.UIAxes = uiaxes(app.HierarchicalClusteringViewerPanel);
            title(app.UIAxes, 'Title')
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Y')
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = demo2

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end